classdef Stats8 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SegnemtationandstatisticsUIFigure  matlab.ui.Figure
        OpenImageButton                 matlab.ui.control.Button
        XLabel                          matlab.ui.control.Label
        YLabel                          matlab.ui.control.Label
        AvailableLampLabel              matlab.ui.control.Label
        AvailableLamp                   matlab.ui.control.Lamp
        SegmentationparametersPanel     matlab.ui.container.Panel
        GaussianblursigmaSpinnerLabel   matlab.ui.control.Label
        gbSpinner                       matlab.ui.control.Spinner
        HmininathresholdLabel           matlab.ui.control.Label
        threshSpinner                   matlab.ui.control.Spinner
        SegmentationregionLabel         matlab.ui.control.Label
        segSpinner                      matlab.ui.control.Spinner
        steponkeypressLabel             matlab.ui.control.Label
        steponkeypress                  matlab.ui.control.Spinner
        DisplayOptionsPanel             matlab.ui.container.Panel
        Atho1Label                      matlab.ui.control.Label
        AthoSwitch                      matlab.ui.control.Switch
        ZO1brightnessSliderLabel        matlab.ui.control.Label
        ZO1brightnessSlider             matlab.ui.control.Slider
        Atho1brightnessSliderLabel      matlab.ui.control.Label
        Atho1brightnessSlider           matlab.ui.control.Slider
        SkeletonLabel                   matlab.ui.control.Label
        SkelSwitch                      matlab.ui.control.Switch
        SetBaseApexAxisButton           matlab.ui.control.Button
        MarkCellsPanel                  matlab.ui.container.Panel
        MarkallHCsButton                matlab.ui.control.Button
        refresheveryclicksSpinnerLabel  matlab.ui.control.Label
        refresheveryclicksSpinner       matlab.ui.control.Spinner
        MiddleclicksegmentationerrorLabel  matlab.ui.control.Label
        RightclickmiddlerowHCLabel      matlab.ui.control.Label
        LeftclickHCLabel                matlab.ui.control.Label
        MarkButton                      matlab.ui.control.Button
        Atho1thresholdLabel             matlab.ui.control.Label
        athoThreshSpinner               matlab.ui.control.Spinner
        ClearallmarkingsButton          matlab.ui.control.Button
        MarksmallcellsButton            matlab.ui.control.Button
        AreathresholdLabel              matlab.ui.control.Label
        AreaThreshSpinner               matlab.ui.control.Spinner
        ClearsmallcellsmarkingsButton   matlab.ui.control.Button
        resetskeletonlabelButton        matlab.ui.control.Button
        qsideSCwtopbottomSCLabel        matlab.ui.control.Label
        SegmentAllButton                matlab.ui.control.Button
        SavingloadingPanel              matlab.ui.container.Panel
        savesegmentationButton          matlab.ui.control.Button
        NameformatLabel                 matlab.ui.control.Label
        NameformatEditField             matlab.ui.control.EditField
        loadsegmentationButton          matlab.ui.control.Button
        saveeveryclicksSpinnerLabel     matlab.ui.control.Label
        saveeveryclicksSpinner          matlab.ui.control.Spinner
    end

    
    properties (Access = private)
        segName    % Segmentation file name
        Im         % The ZO1 image
        ImAtho     % The Atho1 image
        Markings   % An image of the cell markings
        Skeleton   % The skeleton og the image
        SkelLabel  % The labeling of the cells in the skeleton
        cellsType  % The type of each cell in the labeled image
        dispIm     % The display image (combined RGB)
        ImFig      % The figure that holds the display image
        ImSide     % Side image for big segmentations
        tmpMarkLims = []    % marks the limits of a temporary marking
        fileID     % the ID of the statistics file
        axisPoints % the points that make the line that goes along the cochlea
        rp         % region props: centroid and area of each cell in SkelLabel
    end
    
    methods (Access = private)
        
        function toggleLamp(app, on)
            if on
                app.AvailableLamp.Color = [0 1 0];
                app.AvailableLampLabel.Text = 'Available';
            else
                app.AvailableLamp.Color = [1 0 0];
                app.AvailableLampLabel.Text = 'Busy';
            end
        end
        
        function segmentRegion(app, row, col)
            % segments the region(s) centered at (row,col)
            % saves the skeleton in app.Skeleton
            
            % filters for the segmentation
            gb = app.gbSpinner.Value;                % gaussian blur sigma
            hthresh = app.threshSpinner.Value;       % H mininma threshold
            seg_region = app.segSpinner.Value;       % segmentation region
            
            % initiate skeleton
            app.Skeleton(:,:) = 0;
            for i=1:length(row)
                [I, lims] = getRegionIm(app, row(i), col(i), seg_region);     % region image
                
                if gb ~= 0
                    Im_b = imgaussfilt(I, gb);            % gaussian blur
                else
                    Im_b = I;
                end
                Im_h = imhmin(Im_b, hthresh);             % flatening minima
                ws = watershed(Im_h);                     % flooding with watershed
                skel = uint8(ws==0);                      % taking the skeleton from the watershed image
                skel = bwmorph(skel, 'shrink', inf);      % removing loose bonds
                skel = bwmorph(skel, 'clean', inf);       % cleaning isolated pixels
                
                app.Skeleton(lims(1):lims(2),lims(3):lims(4)) = uint8(logical(app.Skeleton(lims(1):lims(2),lims(3):lims(4)))|logical(skel));
            end
            app.Skeleton = bwmorph(app.Skeleton, 'shrink', inf);
            app.Skeleton = app.Skeleton*255;
            
            % plotting the skeleton on the image
            app.dispIm(:,:,3) = app.Skeleton(:,:);
            app.ImFig.CData(:,:, 3) = app.dispIm(:,:,3) + app.Markings(:,:,3);
        end
        
        function [I, lims, Idisp] = getRegionIm(app, row, col, blocksize)
            L = round(blocksize/2);
            [h, w] = size(app.Im);
            top_lim   = (row-L)*(row-L>0)  + (row-L<=0);
            bottom_lim  = (row+L)*(row+L<=h) + h*(row+L>h);
            left_lim    = (col-L)*(col-L>0)  + (col-L<=0);
            right_lim = (col+L)*(col+L<=w) + w*(col+L>w);
            I = app.Im(top_lim:bottom_lim, left_lim:right_lim);
            lims = [top_lim, bottom_lim, left_lim, right_lim];
            Idisp = app.dispIm(top_lim:bottom_lim, left_lim:right_lim,:);
            Idisp(:,:,3) = app.Skeleton(top_lim:bottom_lim, left_lim:right_lim);
        end
     
        function n = cellNeighbors(app, Lcells, cellNum)
            % returns an array of neighbors (indices) for the cell (cellNum)
            % Lcell is a label image with cells' indices
            
            se = strel('disk',2);
            cell_pix= (Lcells==cellNum);         % logical array of the cell's pixels in Lcells.
            cell_pix = imdilate(cell_pix, se);   % dilating the region of the cell in 2 pixels.
            nhood = Lcells(cell_pix);            % the close area of the cell.
            un = unique(nhood);                  % saving all the cells' indices in the area of the current cell
            un = un(un~=cellNum & un~=0);        % without the current cell and the skeleton (0)
            bonds_length = zeros(length(un),1);  % an array that will contain the lengths of the bonds (approximatly)
            for j=1:length(un)
                bonds_length(j) = length(find(nhood==un(j)));
            end
            bonds_length = bonds_length./sum(bonds_length); % normalize to 1
            n = un(bonds_length > 0.05);                    % defines 5% cutoff
            
        end
        
        function setAxisPoints(app, points)
            L = zeros(length(points)-1,1);     % L(i) is the distance between point i to t+1
            for i=1:length(points)-1
                L(i) = pdist([points(i,:) ; points(i+1,:)]);
            end
            Ltot = sum(L);
            L = L/Ltot*1000;
            % each line will be break into L(i) points
            % total number of points will be ~1000
            p = [];
            for i=1:length(points)-1
                px = linspace(points(i,1), points(i+1,1), round(L(i)))';
                py = linspace(points(i,2), points(i+1,2), round(L(i)))';
                p = [p; px(2:end) py(2:end)]; % the last point in line i is the first point in line i+1
            end
            app.axisPoints = p;
        end
        
        function x = locationAlongAxis(app, row, col)
            % returns the closest axis point to (row,col)
            axPoints = length(app.axisPoints);
            D = zeros(axPoints, 1);
            for i=1:axPoints
                p = [app.axisPoints(i,1), app.axisPoints(i,2)];
                D(i) = pdist([row, col;  p]);
            end
            x = find(D == min(D), 1);
        end
        
        function [nbrs, pos] = markSelected(app, row, col, type)
            % mark the cell in (row,col) with the indicated type
            selected = app.SkelLabel(row, col); % the index of the selected cell
            if selected ~= 0 && selected ~= 1 % 1 is ususally the background
                app.cellsType(selected) = type*( app.cellsType(selected) ~= type ); % marks the selected cell and removes the marking if it's already marked
                [~, lims, Idisp] = getRegionIm(app, row, col, app.segSpinner.Value);
                Idisp = updateSideImage(app, Idisp, lims, 0.5); % shows a small updated side image of the cell's region (for performance)
                
                popSideWindow(app, Idisp);
                
                nbrs = numel(cellNeighbors(app, app.SkelLabel, selected)); % number of neighbors of the cell
                pos = locationAlongAxis(app, row, col);
                app.ImFig.Parent.XLabel.String = ['Neighbors: ', num2str(nbrs), '  pos:', num2str(pos) ];
            end
            
        end
        
        function Idisp = updateSideImage(app, Idisp, lims, brightness)
            % lims is an array of the position of Lcells in the display image
            % brightness is a value from 0 to 1
            
            Idisp(:,:,3) = app.Skeleton(lims(1):lims(2),lims(3):lims(4));
            Lcells = app.SkelLabel(lims(1):lims(2), lims(3):lims(4));
            colors = [255, 255, 100; ...  % color for HC
                0,   100, 255; ...  % color for middle HC
                200, 0  , 0  ; ...    % color for errors
                0  , 255  , 0  ; ...  % color for small cells
                255  , 0  , 255  ; ...  % color for left-right SCs
                255  , 100  , 0  ];  % color for top-bottom SCs
            cellNums = unique(Lcells);      % all the cells in the region
            cellNums(cellNums==0) = [];     % not including segmented lines
            for i=1:length(cellNums)
                pix = (Lcells == cellNums(i)); % logical matrix of the pixels of the current cell
                type = app.cellsType(cellNums(i));
                switch type
                    case 0
                        continue;
                    case 3 
                        tind = 1; % HC (not OHC2)
                    case 4
                        tind = 2; % OHC2
                    case 99 
                        tind = 3; % segmentation error
                    case 88
                        tind = 4; % small cell marking
                    case 5
                        tind = 5; % side SC
                    case 6
                        tind = 6; % top/bottom SC
                end
                for c=1:3
                    Idisp(:,:,c) = uint8( Idisp(:,:,c) + uint8(colors(tind,c)*brightness*pix) );
                end
            end
            
        end
        
        function deleteClosestBond(app, row, col)
            
            [~, lims, Idisp] = getRegionIm(app, row, col, app.segSpinner.Value);  % small image of the relevant area
            skel = logical(Idisp(:,:,3));    % the skeleton for this region
            
            [row0, col0] = closestPoint(app, skel, row + 1 - lims(1), col + 1 - lims(3));    % returns the closest point on skel to (row,col)
            
            skel_corrected = deleteLine(app, skel, row0, col0, 1);
            removed_bond = skel - skel_corrected;
            
            % update the skeleton of the entire image
            app.Skeleton(lims(1):lims(2),lims(3):lims(4)) = 255*uint8(skel_corrected);
            
            % update the skeleton label image:
            % find the adjecent cells to (row0,col0)
            pix = app.SkelLabel(row0-2+lims(1):row0+lims(1),col0-2+lims(3):col0+lims(3));
            adj_cells = unique(pix);
            adj_cells(adj_cells==0) = [];
            % if one of the cells is already marked, the new combined cell is marked with this cell's index.
            overtaking = adj_cells(find(app.cellsType(adj_cells) ~= 0 & app.cellsType(adj_cells) ~= 99,1));
            if isempty(overtaking)
                overtaking = adj_cells(1);
            end
            % in the case that the deleted bond seperates the outer background (cell number 1):
            if ~isempty(find(adj_cells == 1,1))
                overtaking = 1;
            end
            overtaken = adj_cells(adj_cells~=overtaking);
            for i=1:length(overtaken)
                app.SkelLabel(app.SkelLabel==overtaken(i)) = overtaking;
                app.cellsType(overtaken(i)) = 99;
            end
            rb = logical(removed_bond);
            app.SkelLabel(lims(1):lims(2),lims(3):lims(4)) = app.SkelLabel(lims(1):lims(2),lims(3):lims(4)).*(double(~rb)) + overtaking*double(rb);
            
            % display the new image in the side window
            Idisp = updateSideImage(app, Idisp, lims, 0.5);
            
            popSideWindow(app, Idisp);
            
        end
        
        function skel = deleteLine(app, skel, row, col, initial_point)
            % deletes the line that contains the pixel (row,col)
            % 'skel' must be logical
            skel(row, col) = 0; % removes a single pixel from skel at (row,col)
            [h, w] = size(skel);
            pix = skel(max(row-1,1):min(row+1,h), max(col-1,1):min(col+1,w)); % gets the nearest neighboring pixels
            [rs, cs] = find(pix==1); % position of the neighboring edges
            % repeat the function for the edge pixels
            if ~initial_point
                if numel(rs) == 1
                    skel = deleteLine(app, skel, row+rs-2, col+cs-2, 0);
                else
                    if max(max(bwlabel(pix))) > 1
                        skel(row, col) = 1; % undeletes the last pixel
                    end
                end
            else
                for i=1:numel(rs)
                    skel = deleteLine(app, skel, row+rs(i)-2, col+cs(i)-2, 0);
                end
            end
        end
        
        function [rs,cs] = closestPoint(app, skel,row,col)
            % finds and returns the closest point to (row,col) on skel
            [rows,cols] = find(skel==1); % all the points on the skeleton
            
            d2 = (col-cols).^2+(row-rows).^2; % the squared distance from each point on skel to (row,col)
            
            [~,ind]=min(d2); % 'ind' is the index of the closest point on 'skel' to (row,col)
            
            cs=cols(ind);
            rs=rows(ind);
            
        end
        
        function addBondLine(app, row, col, row0, col0, isAddMode)
            % adds a new line between [row0,col0] to [row,col]. [row, col] is the pressed point.
            % [row0,col0] is the previous point. if this is the first point (isAddMode=0) then this is the closest point to [row,col]
            
            [I, lims, Idisp] = getRegionIm(app, row, col, app.segSpinner.Value);  % small image of the relevant area
            skel = logical(Idisp(:,:,3));    % the skeleton for this region
            % readjusting the rows and cols relative to the small image
            row = row + 1 - lims(1); row0 = row0 + 1 - lims(1);
            col = col + 1 - lims(3); col0 = col0 + 1 - lims(3);
            
            if ~isAddMode
                [row0, col0] = closestPoint(app, skel, row, col);    % returns the closest point on skel to (row,col)
            end
            skel = addLineToSkel(app, skel, row, col, row0, col0);
            % add the new line to the entire skeleton
            app.Skeleton(lims(1):lims(2),lims(3):lims(4)) = 255*uint8(skel);
            
            % display the new image in the side window
            Idisp = updateSideImage(app, Idisp, lims, 0.5);
            
            popSideWindow(app, Idisp);
            
        end
        
        function finishNewBond(app, row, col, row0, col0)
            % finishes the construction of the new bond
            
            [~, lims, Idisp] = getRegionIm(app, row, col, app.segSpinner.Value);  % small image of the relevant area
            skel = logical(Idisp(:,:,3));    % the skeleton for this region
            % readjusting the rows and cols relative to the small image
            row = row + 1 - lims(1); row0 = row0 + 1 - lims(1);
            col = col + 1 - lims(3); col0 = col0 + 1 - lims(3);
            
            [row1, col1] = closestPoint(app, skel, row, col);    % returns the closest point on skel to (row,col)
            
            skel = addLineToSkel(app, skel, row1, col1, row0, col0);
            skel = bwmorph(skel, 'thin', inf);
            
            % add the new line to the entire skeleton
            app.Skeleton(lims(1):lims(2),lims(3):lims(4)) = 255*uint8(skel);
                        
            % update the skeleton label
            skel_label = app.SkelLabel(lims(1):lims(2),lims(3):lims(4));
            cell_num = skel_label(row0, col0);
            skel_label(app.Skeleton(lims(1):lims(2),lims(3):lims(4)) == 255) = 0;
            
            cell_lbl = bwlabel(skel_label == cell_num, 4); % label image of the cell
            n = max(max(cell_lbl)); % number of parts of the splitted cell
            if n > 1
                % check that the new cell doesn't touch the boundaries of the small image
                nn = 1;
                for i=1:n-1
                    if ~isempty(find( (cell_lbl(1,:) == nn | cell_lbl(end,:) == nn | cell_lbl(:,1) == nn | cell_lbl(:,end) == nn) == 1,1))
                        nn=nn+1;
                    else
                        break;
                    end
                end
                nc = length(app.cellsType);
                skel_label(cell_lbl==nn) = nc + 1; % adds the new cell with a new index
                rp_new = regionprops(cell_lbl==nn, 'Area', 'Centroid');
                app.rp(nc+1).Centroid = rp_new.Centroid + [lims(3)-1,lims(1)-1];
                app.rp(nc+1).Area = rp_new.Area;
                app.cellsType = [app.cellsType; 0];
                app.SkelLabel(lims(1):lims(2),lims(3):lims(4)) = skel_label;
            end
            
            % display the new image in the side window
            Idisp = updateSideImage(app, Idisp, lims, 0.5);
            
            popSideWindow(app, Idisp);
            
        end
        
        function skel = addLineToSkel(app, skel, row, col, row0, col0)
            % the function gets starting and ending points and adds a line to the skeleton
            x = col; y = row; x0 = col0; y0 = row0;
            if x == x0
                skel(min(y,y0):max(y,y0), x) = 1;
            else
                % finding the line parameters (y = mx + b)
                m = (y - y0)/(x - x0);
                b = y - m*x;
                
                x1 = min(x,x0);
                x2 = max(x,x0);
                y1 = min(y,y0);
                y2 = max(y,y0);
                
                if abs(m)<=1
                    xs = linspace(x1, x2, x2-x1+1);
                    ys = round(m.*xs + b);
                    
                    for s=1:x2-x1+1
                        skel(ys(s),xs(s)) = 1;
                    end
                else
                    ys = linspace(y1, y2, y2-y1+1);
                    xs = round((ys-b)./m);
                    
                    for s=1:y2-y1+1
                        skel(ys(s),xs(s)) = 1;
                    end
                end
            end
            
        end
        
        function refreshMainWindow(app, counter)
            c = app.refresheveryclicksSpinner.Value;
            if (c~=0 && mod(counter, c) == 0) || counter == 0
                marked = find(app.cellsType ~= 0); % indices of marked cells
                lbl = uint8(zeros(size(app.SkelLabel,1), size(app.SkelLabel,2),3)); % rgb marking labeling
                wb = waitbar(0, 'refreshing main view');
                [h, w] = size(app.SkelLabel);
                nm = length(marked);
                brightness = 0.5;
                colors = [255, 255, 100; ...  % color for HC
                0,   100, 255; ...  % color for middle HC
                200, 0  , 0  ; ...    % color for errors
                0  , 255  , 0  ; ...  % color for small cells
                255  , 0  , 255  ; ...  % color for left-right SCs
                255  , 100  , 0  ];  % color for top-bottom SCs
                for i=1:nm
                    waitbar(i/nm);
                    a = app.rp(marked(i)).Area;
                    sa = sqrt(a);
                    r = app.rp(marked(i)).Centroid(2);
                    c = app.rp(marked(i)).Centroid(1);
                    lims = [max(r-3*sa,1),min(r+3*sa,h), max(c-3*sa,1), min(c+3*sa, w)];
                    area_lbl = app.SkelLabel(lims(1):lims(2),lims(3):lims(4));
                    type = app.cellsType(marked(i));
                    switch type
                        case 3
                            tind = 1;
                        case 4
                            tind = 2;
                        case 99
                            tind = 3;
                        case 88
                            tind = 4;
                        case 5
                            tind = 5;
                        case 6
                            tind = 6;
                    end
                    for chnl = 1:3
                        lbl(lims(1):lims(2),lims(3):lims(4),chnl) = lbl(lims(1):lims(2),lims(3):lims(4), chnl) + colors(tind,chnl)*uint8( area_lbl == marked(i) );
                    end
                end
                app.ImFig.CData(:,:,1:2) = uint8(app.dispIm(:,:,1:2) + brightness.*uint8(lbl(:,:,1:2)) );
                if strcmp(app.SkelSwitch.Value,'On')
                    app.ImFig.CData(:,:,3) = uint8(double(app.Skeleton) + double(brightness.*uint8(lbl(:,:,3))) );
                else
                    app.ImFig.CData(:,:,3) = uint8(double(brightness.*uint8(lbl(:,:,3))) );
                end
                close(wb);
            end
        end
        
        function saveSegmentation(app, prfx)
            wb = waitbar(0, 'saving segmentation');
            if nargin < 2
                prfx = ['seg_', datestr(now,'dd-mm-yyyy_HH-MM')];
            end
            segN = [prfx ,'.mat'];
            
            % fields to save
            skel = app.Skeleton;
            skel_label = app.SkelLabel;
            cells_type = app.cellsType;
            axis_points = app.axisPoints;
            reg_props = app.rp;
            
            waitbar(0.5);
            mkdir(app.NameformatEditField.Value); % makes a folder with the name of the project
            save([app.NameformatEditField.Value, '\', segN], 'skel', 'skel_label', 'cells_type', 'axis_points', 'reg_props');
            close(wb);
        end
        
        function loadSegmentation(app)
            % image must be loaded before loading!
            [FileName,PathName]=uigetfile({'*.*'}); % select saved segmentation file
            FullName=[PathName,FileName];
            wb = waitbar(0, 'loading segmentation');
            seg = load(FullName);
            app.Skeleton = seg.skel;
            app.SkelLabel = seg.skel_label;
            app.cellsType = seg.cells_type;
            app.axisPoints = seg.axis_points;
            app.rp = seg.reg_props;
            close(wb);
            refreshMainWindow(app, 0);
        end
        
        function panImage(app, dx, dy)
            % moves the axes in (dx,dy)
            lims = axis;
            lims(1:2) = lims(1:2) + dx;
            lims(3:4) = lims(3:4) + dy;
            axis(lims);
        end
        
        function popSideWindow(app, Idisp)
            figure(99);
            imshow(Idisp);
            figure(1);
        end
        
        
    end


    methods (Access = private)

        % Button pushed function: OpenImageButton
        function OpenImageButtonPushed(app, event)
            % images must be in the right name format.
            % ZO1 images must end with 'zo1.tif'. 
            % Atho1 images must end with 'atho1.tif'.
            % Both images must be a signle channle single plane
            
            close all
            clc
            warning off
            
            % open ZO1 image
            disp('Uploading ZO1 image...');
            [FileName,PathName]=uigetfile({'*.*'});
            FullName = [PathName,FileName];
            app.segName = FileName; % name for the segmentation project
            
            app.Im = imread(FullName); % upload ZO1 image into im
            if size(app.Im,3) == 3 % if the image is saves as RGB the convert to grayscale
                app.Im = uint8(rgb2gray(app.Im));
            end    
            [h, w] = size(app.Im); % image dimensions 
        
            app.dispIm = uint8(zeros(h, w, 3)); % the rgb image that's displayed for the user
            app.dispIm(:,:,1) = app.Im;  % ZO1 is displayed in the red channel
            
            app.NameformatEditField.Value = FileName(1:strfind(FileName, 'zo1')-2); % display the name of the image in the text field
            disp('ZO1 image uploaded.');
            disp('Uploading Atho1 image...');
            
            % open Atoh1 image
            zo_pos = strfind(FileName, 'zo1'); % index of 'zo1' in the image name
            AthoFile = [FileName(1:zo_pos(end)-1), 'atho1.tif'];
            FullName=[PathName,AthoFile];
            if exist(FullName, 'file') == 2 % opens the atoh1 image only if it exist
                app.ImAtho = imread(FullName);
                if size(app.ImAtho,3) == 3
                    app.ImAtho = rgb2gray(app.ImAtho);
                end
                app.dispIm(:,:,2) = app.ImAtho; % atoh1 is displayed in the green channel
                disp('Atho1 image upladed.');
                app.AthoSwitch.Enable = 'on';
                app.Atho1brightnessSlider.Enable = 'on';
            else
                disp('Atho1 file not found');
            end
            
            % displaying the RGB image.
            disp('Displaying image...');
            app.ImFig = imshow(app.dispIm); % the figure that contains the display image
            
            % initializing Markings image and Skeleton image
            app.Markings = uint8(zeros(h, w, 3)); % will contain marking for different cell types
            app.Skeleton = uint8(zeros(h, w));  % will contain the skeleton of the image
            
            % a trick to refocus the gui window:
            app.SegnemtationandstatisticsUIFigure.Visible = 'off';
            app.SegnemtationandstatisticsUIFigure.Visible = 'on';
            
            app.SetBaseApexAxisButton.Enable = 'on';
            app.ZO1brightnessSlider.Enable = 'on';
        end

        % Value changed function: AthoSwitch
        function AthoSwitchValueChanged(app, event)
            value = app.AthoSwitch.Value;
            app.Atho1brightnessSlider.Enable = value;
            if strcmp(value,'On')
                app.dispIm(:,:,2) = uint8(app.ImAtho*(10^(app.Atho1brightnessSlider.Value/50 - 1)));
            else
                [h, w] = size(app.Im);
                app.dispIm(:,:,2) = zeros(h, w);
            end
            app.ImFig.CData(:,:,2) = app.dispIm(:,:,2) + app.Markings(:,:,2);
        end

        % Value changed function: ZO1brightnessSlider
        function ZO1brightnessSliderValueChanged(app, event)
            value = app.ZO1brightnessSlider.Value;
            app.dispIm(:,:,1) = uint8(app.Im*(10^(value/50 - 1)));
            app.ImFig.CData(:,:,1) = uint8(app.dispIm(:,:,1) + app.Markings(:,:,1));
        end

        % Value changed function: Atho1brightnessSlider
        function Atho1brightnessSliderValueChanged(app, event)
            value = app.Atho1brightnessSlider.Value;
            app.dispIm(:,:,2) = uint8(app.ImAtho*(10^(value/50 - 1)));
            app.ImFig.CData(:,:,2) = uint8(app.dispIm(:,:,2) + app.Markings(:,:,2));
        end

        % Button pushed function: SetBaseApexAxisButton
        function SetBaseApexAxisButtonPushed(app, event)
            button = 0;
            points = []; % marked axis points
            pointSize = size(app.Im)/100;
            lx = round(pointSize(2)/2); % x 'radius' of point
            ly = round(pointSize(1)/2); % y 'radius' of point
            while button~=3 % while right mouse click is not pressed
                [x,y,button] = ginput2(1); 
                col = round(x);
                row = round(y); % coordinated of clicked point
                app.XLabel.Text =  ['X: ' num2str(col)];
                app.YLabel.Text = ['Y: ' num2str(row)]; % display clicked point in gui
                points = [points; row, col]; % add point to the list
                app.ImFig.CData(row-ly:row+ly, col-lx:col+lx, :) = 255; % show point over figure
            end
            setAxisPoints(app, points);
            app.ImFig.Parent.XLabel.String = 'Cochlear axis saved!';
            % delete markings
            ZO1brightnessSliderValueChanged(app, event);
            if strcmp(app.Atho1brightnessSlider.Enable, 'on')
                Atho1brightnessSliderValueChanged(app, event);
            else
                app.ImFig.CData(:,:,2) = 0;
            end
            app.ImFig.CData(:,:,3) = 0;
        end

        % Button pushed function: MarkallHCsButton
        function MarkallHCsButtonPushed(app, event)
            % automatically tries to detect HCs by atoh1
            toggleLamp(app, 0);
            I = imadjust(app.ImAtho);   % adjusting the intensity
            I = imgaussfilt(I);         % bluring the image
            nc = length(app.cellsType);
            [h,w] = size(I);
            atho = zeros(nc, 1); % average atho1 signal: total atho1 signal divided by the cell's area
            wb = waitbar(0, 'calculating signal for each cell');
            for i=2:nc
                waitbar(i/nc);
                a = app.rp(i).Area; % area of cell i
                sa = sqrt(a); % length scale of the cell
                r = app.rp(i).Centroid(2); % row of centroid
                c = app.rp(i).Centroid(1); % column of centroid
                lims = [max(r-3*sa,1),min(r+3*sa,h), max(c-3*sa,1), min(c+3*sa, w)]; % a small region that contains the cell
                area_lbl = app.SkelLabel(lims(1):lims(2),lims(3):lims(4)); % label image only from that region
                area_I = I(lims(1):lims(2),lims(3):lims(4)); % atoh1 image only from that region
                atho(i) = sum(sum(area_I(area_lbl == i)))/a;
            end
            close(wb);
            thresh = app.athoThreshSpinner.Value;
            % mark each cell with atho signal above thresh as HC
            app.cellsType(atho > thresh) = 3;
            refreshMainWindow(app, 0);
            toggleLamp(app, 1);
        end

        % Button pushed function: SegmentAllButton
        function SegmentAllButtonPushed(app, event)
            % segments regions determined by the marked axis points
            segStep = 10; 
            toggleLamp(app, 0);
            segPoints = app.axisPoints(1:segStep:size(app.axisPoints,1),:); % the center points for the segmentation
            segmentRegion(app, segPoints(:,1), segPoints(:,2)); % sets app.Skeleton
            app.SkelLabel = bwlabel(1-logical(app.Skeleton),4); % labels the cells by indices
            app.cellsType = zeros(max(max(app.SkelLabel)), 1); % initiated the list for cell type
            app.rp = regionprops(app.SkelLabel, 'Area', 'Centroid'); % region props elements that contains area and centroid for each cell
            app.SkelSwitch.Enable = 'on';
            toggleLamp(app, 1);
        end

        % Button pushed function: MarkButton
        function MarkButtonPushed(app, event)
            button = 1;
            toggleLamp(app, 0);
            app.MarkButton.Enable = 'off';
            click_count = 0; % counts the number of clicks by the user
            isAddMode = 0; % indicator for wether the user started adding a junction manually
            figure(1); % prompts the figure
            while button~=27
                [x,y,button] = ginput2(1);
                col = round(x);
                row = round(y);
                app.XLabel.Text =  ['X: ' num2str(col)];
                app.YLabel.Text = ['Y: ' num2str(row)];
                switch (button)
                    case 1       % left mouse click
                        markSelected(app, row, col, 3); % HC (not OHC2)
                        click_count = click_count + 1;
                    case 3       % right mouse click
                        markSelected(app, row, col, 4); % OHC2
                        click_count = click_count + 1;
                    case 2       % middle mouse click
                        markSelected(app, row, col, 99); % segmentation error
                        click_count = click_count + 1;
                    case 113       % 'q' keypress
                        markSelected(app, row, col, 5); % side SC
                        click_count = click_count + 1;
                    case 119       % 'w' keypress
                        markSelected(app, row, col, 6); % top/bottom SC
                        click_count = click_count + 1;
                    case 97 % 'a' key. add new point to the new bond
                        if ~isAddMode
                            addBondLine(app, row, col, 0, 0, 0);
                            isAddMode = 1;
                        else
                            addBondLine(app, row, col, row0, col0, 1);
                        end
                        row0 = row;
                        col0 = col;
                    case 115 % 's' key. finish the bond addition.
                        if isAddMode
                            finishNewBond(app, row, col, row0, col0);
                            isAddMode = 0;
                        end
                    case 100 % the key 'd' - delete the nearest point
                        deleteClosestBond(app, row, col);
                    case 114 % the key 'r' - refresh main window
                        refreshMainWindow(app, 0);
                    case 122 % the key 'z' - toggle atoh1
                        value = app.AthoSwitch.Value;
                        if strcmp(value,'On')
                            app.AthoSwitch.Value = 'Off';
                        else
                            app.AthoSwitch.Value = 'On';
                        end
                        app.AthoSwitchValueChanged(app);
                    case 120 % the key 'x' - toggle skeleton
                        value = app.SkelSwitch.Value;
                        if strcmp(value,'On')
                            app.SkelSwitch.Value = 'Off';
                        else
                            app.SkelSwitch.Value = 'On';
                        end
                        app.SkelSwitchValueChanged(app);
                    case 99 % the key 'c' - remove markings
                        app.ImFig.CData = app.dispIm;
                    case 28
                        panImage(app, -app.steponkeypress.Value, 0);
                    case 29
                        panImage(app, app.steponkeypress.Value, 0);
                    case 30
                        panImage(app, 0, -app.steponkeypress.Value);
                    case 31
                        panImage(app, 0, app.steponkeypress.Value);
                        
                end
                refreshMainWindow(app, click_count);
                if app.saveeveryclicksSpinner.Value~=0 && mod(click_count, app.saveeveryclicksSpinner.Value)==0
                    saveSegmentation(app);
                end
            end
            saveSegmentation(app);
            toggleLamp(app, 1);
            app.MarkButton.Enable = 'on';
        end

        % Button pushed function: ClearallmarkingsButton
        function ClearallmarkingsButtonPushed(app, event)
            choice = questdlg('Are you sure you want to delete all the cell markings?', ...
               	'Clear all markings', ...
               	'Yes','No','No');
            % Handle response
            switch choice
                case 'Yes'
                    app.cellsType(:) = 0;
                    refreshMainWindow(app, 0);
                case 'No'
            end
            
        end

        % Value changed function: SkelSwitch
        function SkelSwitchValueChanged(app, event)
            value = app.SkelSwitch.Value;
            if strcmp(value,'On')
                app.dispIm(:,:,3) = uint8(app.Skeleton);
            else
                app.dispIm(:,:,3) = 0;
            end
            app.ImFig.CData(:,:,3) = app.dispIm(:,:,3) + app.Markings(:,:,3);
        end

        % Button pushed function: savesegmentationButton
        function savesegmentationButtonPushed(app, event)
            saveSegmentation(app);
        end

        % Button pushed function: loadsegmentationButton
        function loadsegmentationButtonPushed(app, event)
            loadSegmentation(app);
        end

        % Button pushed function: MarksmallcellsButton
        function MarksmallcellsButtonPushed(app, event)
            % automatically detect cells smaller that chosen threshold
            nc = length(app.cellsType);
            athesh = app.AreaThreshSpinner.Value;
            for i=2:nc
                if app.rp(i).Area <= athesh
                    app.cellsType(i) = 88; % small cell type
                end
            end
            refreshMainWindow(app, 0);
        end

        % Button pushed function: ClearsmallcellsmarkingsButton
        function ClearsmallcellsmarkingsButtonPushed(app, event)
            app.cellsType(app.cellsType==88) = 0;
            refreshMainWindow(app, 0);
        end

        % Button pushed function: resetskeletonlabelButton
        function resetskeletonlabelButtonPushed(app, event)
            choice = questdlg('Are you sure you want to delete all the cell markings and reset skeleton label?', ...
               	'Clear all markings', ...
               	'Yes','No','No');
            % Handle response
            switch choice
                case 'Yes'
                    app.SkelLabel = bwlabel(1-logical(app.Skeleton),4);
                    app.cellsType = zeros(max(max(app.SkelLabel)), 1);
                    app.rp = regionprops(app.SkelLabel, 'Area', 'Centroid');
                    app.Markings = uint8(zeros(size(app.Im,1), size(app.Im,2), 3));
                    refreshMainWindow(app, 0);
                case 'No'
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SegnemtationandstatisticsUIFigure = uifigure;
            app.SegnemtationandstatisticsUIFigure.Name = 'Segnemtation and statistics';
            app.OpenImageButton = uibutton(app.SegnemtationandstatisticsUIFigure, 'push');
            app.OpenImageButton.ButtonPushedFcn = createCallbackFcn(app, @OpenImageButtonPushed, true);
            app.OpenImageButton.Text = 'Open Image';
            app.XLabel = uilabel(app.SegnemtationandstatisticsUIFigure);
            app.XLabel.Text = 'X: 0';
            app.YLabel = uilabel(app.SegnemtationandstatisticsUIFigure);
            app.YLabel.Text = 'Y: 0';
            app.AvailableLampLabel = uilabel(app.SegnemtationandstatisticsUIFigure);
            app.AvailableLampLabel.Text = 'Available';
            app.AvailableLamp = uilamp(app.SegnemtationandstatisticsUIFigure);
            app.SegmentationparametersPanel = uipanel(app.SegnemtationandstatisticsUIFigure);
            app.SegmentationparametersPanel.Title = 'Segmentation parameters:';
            app.GaussianblursigmaSpinnerLabel = uilabel(app.SegmentationparametersPanel);
            app.GaussianblursigmaSpinnerLabel.Text = 'Gaussian blur sigma: ';
            app.gbSpinner = uispinner(app.SegmentationparametersPanel);
            app.gbSpinner.Limits = [0 Inf];
            app.gbSpinner.Value = 1;
            app.HmininathresholdLabel = uilabel(app.SegmentationparametersPanel);
            app.HmininathresholdLabel.Text = 'H minina threshold';
            app.threshSpinner = uispinner(app.SegmentationparametersPanel);
            app.threshSpinner.Limits = [0 100];
            app.threshSpinner.Value = 20;
            app.SegmentationregionLabel = uilabel(app.SegmentationparametersPanel);
            app.SegmentationregionLabel.Text = 'Segmentation region: ';
            app.segSpinner = uispinner(app.SegmentationparametersPanel);
            app.segSpinner.Step = 10;
            app.segSpinner.Limits = [0 Inf];
            app.segSpinner.Value = 180;
            app.steponkeypressLabel = uilabel(app.SegmentationparametersPanel);
            app.steponkeypressLabel.Text = 'step on keypress';
            app.steponkeypress = uispinner(app.SegmentationparametersPanel);
            app.steponkeypress.Step = 10;
            app.steponkeypress.Limits = [0 Inf];
            app.steponkeypress.Value = 50;
            app.DisplayOptionsPanel = uipanel(app.SegnemtationandstatisticsUIFigure);
            app.DisplayOptionsPanel.Title = 'Display Options';
            app.Atho1Label = uilabel(app.DisplayOptionsPanel);
            app.Atho1Label.Text = 'Atho1:';
            app.AthoSwitch = uiswitch(app.DisplayOptionsPanel, 'slider');
            app.AthoSwitch.ValueChangedFcn = createCallbackFcn(app, @AthoSwitchValueChanged, true);
            app.AthoSwitch.Enable = 'off';
            app.AthoSwitch.Value = 'On';
            app.ZO1brightnessSliderLabel = uilabel(app.DisplayOptionsPanel);
            app.ZO1brightnessSliderLabel.Text = 'ZO1 brightness';
            app.ZO1brightnessSlider = uislider(app.DisplayOptionsPanel);
            app.ZO1brightnessSlider.MajorTicks = [];
            app.ZO1brightnessSlider.MajorTickLabels = {};
            app.ZO1brightnessSlider.ValueChangedFcn = createCallbackFcn(app, @ZO1brightnessSliderValueChanged, true);
            app.ZO1brightnessSlider.MinorTicks = [];
            app.ZO1brightnessSlider.Enable = 'off';
            app.ZO1brightnessSlider.Value = 50;
            app.Atho1brightnessSliderLabel = uilabel(app.DisplayOptionsPanel);
            app.Atho1brightnessSliderLabel.Text = 'Atho1 brightness';
            app.Atho1brightnessSlider = uislider(app.DisplayOptionsPanel);
            app.Atho1brightnessSlider.MajorTicks = [];
            app.Atho1brightnessSlider.MajorTickLabels = {};
            app.Atho1brightnessSlider.ValueChangedFcn = createCallbackFcn(app, @Atho1brightnessSliderValueChanged, true);
            app.Atho1brightnessSlider.MinorTicks = [];
            app.Atho1brightnessSlider.Enable = 'off';
            app.Atho1brightnessSlider.Value = 50;
            app.SkeletonLabel = uilabel(app.DisplayOptionsPanel);
            app.SkeletonLabel.Text = 'Skeleton:';
            app.SkelSwitch = uiswitch(app.DisplayOptionsPanel, 'slider');
            app.SkelSwitch.ValueChangedFcn = createCallbackFcn(app, @SkelSwitchValueChanged, true);
            app.SkelSwitch.Value = 'On';
            app.SetBaseApexAxisButton = uibutton(app.SegnemtationandstatisticsUIFigure, 'push');
            app.SetBaseApexAxisButton.ButtonPushedFcn = createCallbackFcn(app, @SetBaseApexAxisButtonPushed, true);
            app.SetBaseApexAxisButton.Enable = 'off';
            app.SetBaseApexAxisButton.Text = 'Set Base-Apex Axis';
            app.MarkCellsPanel = uipanel(app.SegnemtationandstatisticsUIFigure);
            app.MarkCellsPanel.Title = 'Mark Cells:';
            app.MarkallHCsButton = uibutton(app.MarkCellsPanel, 'push');
            app.MarkallHCsButton.ButtonPushedFcn = createCallbackFcn(app, @MarkallHCsButtonPushed, true);
            app.MarkallHCsButton.Text = 'Mark all HCs';
            app.refresheveryclicksSpinnerLabel = uilabel(app.MarkCellsPanel);
            app.refresheveryclicksSpinnerLabel.Text = 'refresh every # clicks';
            app.refresheveryclicksSpinner = uispinner(app.MarkCellsPanel);
            app.refresheveryclicksSpinner.Limits = [0 Inf];
            app.MiddleclicksegmentationerrorLabel = uilabel(app.MarkCellsPanel);
            app.MiddleclicksegmentationerrorLabel.Text = 'Middle click: segmentation error';
            app.RightclickmiddlerowHCLabel = uilabel(app.MarkCellsPanel);
            app.RightclickmiddlerowHCLabel.Text = 'Right click:  middle row HC';
            app.LeftclickHCLabel = uilabel(app.MarkCellsPanel);
            app.LeftclickHCLabel.Text = 'Left click: HC';
            app.MarkButton = uibutton(app.MarkCellsPanel, 'push');
            app.MarkButton.ButtonPushedFcn = createCallbackFcn(app, @MarkButtonPushed, true);
            app.MarkButton.Text = 'Mark';
            app.Atho1thresholdLabel = uilabel(app.MarkCellsPanel);
            app.Atho1thresholdLabel.Text = 'Atho1 threshold';
            app.athoThreshSpinner = uispinner(app.MarkCellsPanel);
            app.athoThreshSpinner.Limits = [0 255];
            app.athoThreshSpinner.Value = 150;
            app.ClearallmarkingsButton = uibutton(app.MarkCellsPanel, 'push');
            app.ClearallmarkingsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearallmarkingsButtonPushed, true);
            app.ClearallmarkingsButton.Text = 'Clear all markings';
            app.MarksmallcellsButton = uibutton(app.MarkCellsPanel, 'push');
            app.MarksmallcellsButton.ButtonPushedFcn = createCallbackFcn(app, @MarksmallcellsButtonPushed, true);
            app.MarksmallcellsButton.Text = 'Mark small cells';
            app.AreathresholdLabel = uilabel(app.MarkCellsPanel);
            app.AreathresholdLabel.Text = 'Area threshold';
            app.AreaThreshSpinner = uispinner(app.MarkCellsPanel);
            app.AreaThreshSpinner.Limits = [1 Inf];
            app.AreaThreshSpinner.Value = 200;
            app.ClearsmallcellsmarkingsButton = uibutton(app.MarkCellsPanel, 'push');
            app.ClearsmallcellsmarkingsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearsmallcellsmarkingsButtonPushed, true);
            app.ClearsmallcellsmarkingsButton.Text = 'Clear small cells markings';
            app.resetskeletonlabelButton = uibutton(app.MarkCellsPanel, 'push');
            app.resetskeletonlabelButton.ButtonPushedFcn = createCallbackFcn(app, @resetskeletonlabelButtonPushed, true);
            app.resetskeletonlabelButton.Text = 'reset skeleton label';
            app.qsideSCwtopbottomSCLabel = uilabel(app.MarkCellsPanel);
            app.qsideSCwtopbottomSCLabel.Text = 'q: side SC    w: top\bottom SC';
            app.SegmentAllButton = uibutton(app.SegnemtationandstatisticsUIFigure, 'push');
            app.SegmentAllButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentAllButtonPushed, true);
            app.SegmentAllButton.Text = 'Segment All';
            app.SavingloadingPanel = uipanel(app.SegnemtationandstatisticsUIFigure);
            app.SavingloadingPanel.Title = 'Saving & loading';
            app.savesegmentationButton = uibutton(app.SavingloadingPanel, 'push');
            app.savesegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @savesegmentationButtonPushed, true);
            app.savesegmentationButton.Text = 'save segmentation';
            app.NameformatLabel = uilabel(app.SavingloadingPanel);
            app.NameformatLabel.Text = 'Name format:';
            app.NameformatEditField = uieditfield(app.SavingloadingPanel, 'text');
            app.loadsegmentationButton = uibutton(app.SavingloadingPanel, 'push');
            app.loadsegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @loadsegmentationButtonPushed, true);
            app.loadsegmentationButton.Text = 'load segmentation';
            app.saveeveryclicksSpinnerLabel = uilabel(app.SavingloadingPanel);
            app.saveeveryclicksSpinnerLabel.Text = 'save every # clicks';
            app.saveeveryclicksSpinner = uispinner(app.SavingloadingPanel);
            app.saveeveryclicksSpinner.Limits = [0 Inf];
        end
    end

    methods (Access = public)

        % Construct app
        function app = Stats8

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SegnemtationandstatisticsUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SegnemtationandstatisticsUIFigure)
        end
    end
end